classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BITRAITLabel        matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        DataEditField       matlab.ui.control.EditField
        DataEditFieldLabel  matlab.ui.control.Label
        DecryptVideoButton  matlab.ui.control.Button
        EncryptVideoButton  matlab.ui.control.Button
        Image               matlab.ui.control.Image
    end

    
    properties (Access = private)
        oldpath = addpath('D:\project\Main Project\Matlab-Project\') % to add path
    end
    
    methods (Access = private)
        
        function results = processVideo(app,event);
            global a;
            app.EditField.Value='Choose video';
            %Step for choosing the video
            [filename, pathname] = uigetfile('D:\project\Main Project\Matlab-Project/*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            %Changing the current folder
            cd (pathname)
            cd ..
            a=VideoReader(filename);
            
            vid = read(a);
             % read the total number of frames
            frames = a.NumFrames;
            % file format of the frames to be saved in
            ST ='.jpg';
            % reading and writing the frames 
            app.EditField.Value='Converting to frames';
            for x = 1 : frames
              
                % converting integer to string
                Sx = num2str(x);
                % concatenating 2 strings
                Strc = strcat(Sx, ST);
                Vid = vid(:, :, :, x);
                cd frames\original
                app.EditField.Value=strcat(num2str((x/frames)*100),"%");
                % exporting the frames
                pause(0.000001);
                imwrite(Vid, Strc);
                cd ..\..
            end
            %Selecting target video
            %Selecting total number of target videos
            app.EditField.Value='Selecting target video';
            targetVideoFolder='D:\project\Main Project\Matlab-Project\video\targetVideo\';
            targetVideoPath=dir([targetVideoFolder '/*.mp4']);
            numberOfTargetVideos=size(targetVideoPath,1);
            %Choosing best target video
            targetVideoChosen=VideoReader('D:\project\Main Project\Matlab-Project\video\targetVideo\1.mp4');
            for x=1 : numberOfTargetVideos
                cd video\targetVideo
                Sx = num2str(x);
                filenameOfTargetVideo=strcat(Sx,'.mp4');
                tempVideo=VideoReader(filenameOfTargetVideo);
                tempVideoFrameNum=tempVideo.NumFrames;
                %Comparing for best targetVideo
                if (tempVideoFrameNum>=frames) && (tempVideoFrameNum<targetVideoChosen.NumFrames)
                    targetVideoChosen=tempVideo;
                end    
                cd ..\..    
            end    
            %Converting the target Video to frames
            app.EditField.Value="Converting target video to frames";
            vid1=read(targetVideoChosen);
            noOfTargetVideoFrames=targetVideoChosen.NumFrames;
            for x = 1 : noOfTargetVideoFrames
                % converting integer to string
                Sx = num2str(x);
                % concatenating 2 strings
                Strc = strcat(Sx, ST);
                Vid = vid1(:, :, :, x);
                cd frames\target
                app.EditField.Value=strcat(num2str((x/noOfTargetVideoFrames)*100),"%");
                % exporting the frames
                pause(0.000001);%just to see the pecent value in display clearly
                imwrite(Vid, Strc);
                cd ..\..
            end
            %Calling the RDH method for hiding in target image
            resultOfEncryption=RDH(app);
            results=resultOfEncryption;
        end
  
        
        function avg1 = RDH(app)
           app.EditField.Value="Hiding original image" 
           originalVideoFramesFolder='D:\project\MAIN PROJECT\Matlab-Project\frames\original';
           originalVideoFramePath=dir([originalVideoFramesFolder '/*.jpg']);
           numberOfOriginalVideoFrames=size(originalVideoFramePath,1);
           disp(numberOfOriginalVideoFrames)
           count11=1
           for loop1=1:numberOfOriginalVideoFrames
                filename = ['frames\original\' num2str(loop1) '.jpg'];%Secret Image path
                S=imread(filename);%Reading to S
                filename = ['frames\target\' num2str(loop1) '.jpg']; %Target Image path
                T=imread(filename);   %Reading to T                
                %Stage 1. fitting the tile images into the target blocks.
                M=128;
                N=128;
                disp(loop1)
                S=imresize(S,[M,N]);
                T=imresize(T,[M,N]);
                subplot(131),imshow(S);title('Secret Image');
                subplot(132),imshow(T);title('Target Image');
                n=4;
                [M,N,ch]=size(S);
                numberOfblocks=M*N/n^2;
        
                muR=zeros(1,M*N/n^2);
                muG=muR;muB=muR;sigmaR=muR;sigmaG=muR;sigmaB=muR; 
                muG1=muR;muB1=muR;sigmaR1=muR;muR1=muR;sigmaG1=muR;sigmaB1=muR; 
                avgstd=muR;avgstd1=muR;
                count=1;
                Stile=cell(1,numberOfblocks);Starget=cell(1,numberOfblocks);
                for i=1:n:M
                    for j=1:n:N
                        tile=S(i:i+n-1,j:j+n-1,:);
                        blk=T(i:i+n-1,j:j+n-1,:);
                        muR(count)=round(mean2(tile(:,:,1))+eps);
                        muG(count)=round(mean2(tile(:,:,2))+eps);
                        muB(count)=round(mean2(tile(:,:,3))+eps);  
                        muR1(count)=round(mean2(blk(:,:,1))+eps);
                        muG1(count)=round(mean2(blk(:,:,2))+eps);
                        muB1(count)=round(mean2(blk(:,:,3))+eps);
                        sigmaR(count)=round((std2(tile(:,:,1))+eps)*10)/10;
                        sigmaG(count)=round((std2(tile(:,:,2))+eps)*10)/10;
                        sigmaB(count)=round((std2(tile(:,:,3))+eps)*10)/10;
                        sigmaR1(count)=round((std2(blk(:,:,1))+eps)*10)/10;
                        sigmaG1(count)=round((std2(blk(:,:,2))+eps)*10)/10;
                        sigmaB1(count)=round((std2(blk(:,:,3))+eps)*10)/10;
                        avgstd(count)=round((sigmaR(count)+sigmaG(count)+sigmaB(count))/3);
                        avgstd1(count)=round((sigmaR1(count)+sigmaG1(count)+sigmaB1(count))/3);
                        Stile{count}=tile;Starget{count}=blk;
                        count=count+1;
                    end
                end
                [val1,tileindx]=sort(avgstd);
                [val2,targetindx]=sort(avgstd1);
                Stile=Stile(tileindx);
                Starget=Starget(targetindx);
                muR=muR(tileindx); muG=muG(tileindx);muB=muB(tileindx);
                muR1=muR1(targetindx); muG1=muG1(targetindx);muB1=muB1(targetindx);
                sigmaR=sigmaR(tileindx);sigmaG=sigmaG(tileindx);sigmaB=sigmaB(tileindx);
                sigmaB1=sigmaB1(targetindx);sigmaG1=sigmaG1(targetindx);sigmaR1=sigmaR1(targetindx);
                for k=1:numberOfblocks
                    ci=Stile{k};
                   nci(:,:,1)=((ci(:,:,1)-muR(k)))+muR1(k);
                   nci(:,:,2)=((ci(:,:,2)-muG(k)))+muG1(k);
                   nci(:,:,3)=((ci(:,:,3)-muB(k)))+muB1(k); 
                   ntile{k}=nci;
                end
                ntile(targetindx)=ntile;
                k=1;
                for i=1:n:M
                    for j=1:n:N
                        Mosaic(i:i+n-1,j:j+n-1,:)=ntile{k};
                        k=k+1;
                    end
                end
                %hide data in image using key
                Mb=[muR1,muG1,muB1,muR,muG,muB,avgstd,avgstd1];
                binary=de2bi(Mb',8);
                binary=binary(:);
                avg=binary;
                Mosaic=uint8(reshape(Mosaic,[M,N,3]));
                subplot(133),imshow(Mosaic)
                title('Encrypted image')
                outputFolder='mosaic'
                outputFileName = fullfile(outputFolder, ['targetImage' num2str(count11) '.png']);
                imwrite(Mosaic,outputFileName);
                count11=count11+1
                app.EditField.Value=strcat(num2str((loop1/numberOfOriginalVideoFrames)*100),"%")
           end  
           app.EditField.Value="Hiding Successful"
           avg1="Done";
           end
      end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DataEditField
        function DataEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: EncryptVideoButton
        function EncryptVideoButtonPushed(app, event)

            processedResult=processVideo(app,app.DataEditField.Value);
            app.EditField.Value=processedResult;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'hacker-g88cd2f243_1920.jpg';
            app.EncryptVideoButton = uibutton(app.UIFigure, 'push');
            app.EncryptVideoButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptVideoButtonPushed, true);
            app.EncryptVideoButton.Text = 'Encrypt Video';
            app.DecryptVideoButton = uibutton(app.UIFigure, 'push');
            app.DecryptVideoButton.Text = 'Decrypt Video';
            app.DataEditFieldLabel = uilabel(app.UIFigure);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.UIFigure, 'text');
            app.DataEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.DataEditField.Placeholder = 'Enter the data';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.BITRAITLabel = uilabel(app.UIFigure);
            app.BITRAITLabel.Text = 'BITRAIT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end